classdef Track_Map_Creator_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DataFileEditFieldLabel  matlab.ui.control.Label
        DataFileEditField       matlab.ui.control.EditField
        BrowseButton            matlab.ui.control.Button
        CreateTrackButton       matlab.ui.control.Button
        TrackMapAxes            matlab.ui.control.UIAxes
        SaveTrackButton         matlab.ui.control.Button
        OBRLapTimeSimulationTrackCreatorLabel  matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        TimeinunitsofsecondsSpeedkmhinunitsofkmhandLatAccelgingsLabel  matlab.ui.control.Label
        ClosedCircuitSwitch     matlab.ui.control.Switch
        egAutocrossLabel        matlab.ui.control.Label
        egEnduranceLabel        matlab.ui.control.Label
    end


    properties (Access = private)
        Track = struct() % Description
    end


    methods (Access = private)

        % Button pushed function: BrowseButton
        function browseForData(app, event)
            [filename, pathname] = uigetfile;
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            app.DataFileEditField.Value = strcat(pathname,filename);
        end

        % Button pushed function: CreateTrackButton
        function createTrack(app, event)
            load(app.DataFileEditField.Value)
            if str2num(app.ClosedCircuitSwitch.Value) == true
                closedCircuit = true;
            else
                closedCircuit = false;
            end
                
            app.Track = createTrackData(Time,LatAccg,Speedkmh,closedCircuit);
            
            Z=zeros(size(app.Track.X));
            cla(app.TrackMapAxes);
            hold(app.TrackMapAxes, 'on')
            surface(app.TrackMapAxes,[app.Track.X';app.Track.X'],[app.Track.Y';app.Track.Y'],[Z';Z'],[app.Track.curvature';app.Track.curvature'],'facecol','no','edgecol','interp','linew',2);
            plot(app.TrackMapAxes,app.Track.X(1),app.Track.Y(1), 'o','MarkerEdgeColor','g');
            hold(app.TrackMapAxes, 'off')
            %Format axis to be identically scaled with track centered
            xRange = max(app.Track.X)-min(app.Track.X);
            yRange = max(app.Track.Y)-min(app.Track.Y);
            axisRange = max(xRange,yRange)+5;
            xMid = (max(app.Track.X)+min(app.Track.X))/2;
            yMid = (max(app.Track.Y)+min(app.Track.Y))/2;
            app.TrackMapAxes.XLim = [xMid-axisRange/2,xMid+axisRange/2];
            app.TrackMapAxes.YLim = [yMid-axisRange/2,yMid+axisRange/2];
            
        end

        % Button pushed function: SaveTrackButton
        function saveTrackData(app, event)
            TrackData = app.Track;
            uisave('TrackData','track');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DataFileEditFieldLabel = uilabel(app.UIFigure);
            app.DataFileEditFieldLabel.Text = 'Data File';
            app.DataFileEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browseForData, true);
            app.BrowseButton.Text = 'Browse';
            app.CreateTrackButton = uibutton(app.UIFigure, 'push');
            app.CreateTrackButton.ButtonPushedFcn = createCallbackFcn(app, @createTrack, true);
            app.CreateTrackButton.Text = 'Create Track';
            app.TrackMapAxes = uiaxes(app.UIFigure);
            title(app.TrackMapAxes, 'Track')
            app.SaveTrackButton = uibutton(app.UIFigure, 'push');
            app.SaveTrackButton.ButtonPushedFcn = createCallbackFcn(app, @saveTrackData, true);
            app.SaveTrackButton.Text = 'Save Track';
            app.OBRLapTimeSimulationTrackCreatorLabel = uilabel(app.UIFigure);
            app.OBRLapTimeSimulationTrackCreatorLabel.Text = 'OBR Lap Time Simulation Track Creator';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Data should be .mat file with three column arrays';
            app.TimeinunitsofsecondsSpeedkmhinunitsofkmhandLatAccelgingsLabel = uilabel(app.UIFigure);
            app.TimeinunitsofsecondsSpeedkmhinunitsofkmhandLatAccelgingsLabel.Text = '''Time'' in units of seconds, ''Speedkmh'' in units of km/h, and ''LatAccelg'' in g''s.  ';
            app.ClosedCircuitSwitch = uiswitch(app.UIFigure, 'slider');
            app.ClosedCircuitSwitch.Items = {'Closed Circuit', 'Open Circuit'};
            app.ClosedCircuitSwitch.ItemsData = {'1', '0'};
            app.ClosedCircuitSwitch.Value = '1';
            app.egAutocrossLabel = uilabel(app.UIFigure);
            app.egAutocrossLabel.Text = '(eg. Autocross)';
            app.egEnduranceLabel = uilabel(app.UIFigure);
            app.egEnduranceLabel.Text = '(eg.Endurance)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Track_Map_Creator_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end